classdef ClassificationHelperApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        UIAxes_2                matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
        RightPanel              matlab.ui.container.Panel
        SaveButton              matlab.ui.control.Button
        ButtonGroup_2           matlab.ui.container.ButtonGroup
        DUSTButton_2            matlab.ui.control.RadioButton
        ESWButton_2             matlab.ui.control.RadioButton
        LoadButton              matlab.ui.control.Button
        ButtonGroup             matlab.ui.container.ButtonGroup
        TSWFButton              matlab.ui.control.RadioButton
        RSWFButton              matlab.ui.control.RadioButton
        makepredictionCheckBox  matlab.ui.control.CheckBox
        DateDatePicker          matlab.ui.control.DatePicker
        ENDButton               matlab.ui.control.Button
        STARTButton             matlab.ui.control.Button
        BACKButton              matlab.ui.control.Button
        NEXTButton              matlab.ui.control.Button
        EMPTYButton             matlab.ui.control.Button
        DUSTButton              matlab.ui.control.Button
        ESWButton               matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        internalindex % Description
        cdf % Description
        indexes % Description
        ESW
        DUST
        EMPTY
        etype = 'ESW'
        cdftype = 'rswf'
        date = [2020,12,27]
        predict = 0
        ogft
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            app.internalindex = 1;
            index = app.indexes(app.internalindex);
            n_samps = app.cdf.SAMPS_PER_CH.data(index);
            ch1 = app.cdf.WAVEFORM_DATA_VOLTAGE.data(1,1:n_samps,index);
            ch2 = app.cdf.WAVEFORM_DATA_VOLTAGE.data(2,1:n_samps,index);
            plot(app.UIAxes,ch1)
            plot(app.UIAxes_2,ch2)
            title(app.UIAxes, sprintf('Channel 1 on %i-%i-%i, index %i', app.date(1), app.date(2), app.date(3), index))
            title(app.UIAxes_2,sprintf('Channel 2 on %i-%i-%i, index %i', app.date(1), app.date(2), app.date(3), index))


        end

        % Button pushed function: NEXTButton
        function NEXTButtonPushed(app, event)
            if app.internalindex < size(app.indexes,2)
                app.internalindex = app.internalindex+1;
                index = app.indexes(app.internalindex);
                n_samps = app.cdf.SAMPS_PER_CH.data(index);
                ch1 = app.cdf.WAVEFORM_DATA_VOLTAGE.data(1,1:n_samps,index);
                ch2 = app.cdf.WAVEFORM_DATA_VOLTAGE.data(2,1:n_samps,index);
                plot(app.UIAxes,ch1)
                plot(app.UIAxes_2,ch2)
                title(app.UIAxes, sprintf('Channel 1 on %i-%i-%i, index %i', app.date(1), app.date(2), app.date(3), index))
                title(app.UIAxes_2,sprintf('Channel 2 on %i-%i-%i, index %i', app.date(1), app.date(2), app.date(3), index))
            end

        end

        % Button pushed function: BACKButton
        function BACKButtonPushed(app, event)
            if app.internalindex > 1
                app.internalindex = app.internalindex-1;
                index = app.indexes(app.internalindex);
                n_samps = app.cdf.SAMPS_PER_CH.data(index);
                ch1 = app.cdf.WAVEFORM_DATA_VOLTAGE.data(1,1:n_samps,index);
                ch2 = app.cdf.WAVEFORM_DATA_VOLTAGE.data(2,1:n_samps,index);
                plot(app.UIAxes,ch1)
                plot(app.UIAxes_2,ch2)
                title(app.UIAxes, sprintf('Channel 1 on %i-%i-%i, index %i', app.date(1), app.date(2), app.date(3), index))
                title(app.UIAxes_2,sprintf('Channel 2 on %i-%i-%i, index %i', app.date(1), app.date(2), app.date(3), index))
            end
        end

        % Button pushed function: ENDButton
        function ENDButtonPushed(app, event)
            app.internalindex = size(app.indexes,2);
            index = app.indexes(app.internalindex);
            n_samps = app.cdf.SAMPS_PER_CH.data(index);
            ch1 = app.cdf.WAVEFORM_DATA_VOLTAGE.data(1,1:n_samps,index);
            ch2 = app.cdf.WAVEFORM_DATA_VOLTAGE.data(2,1:n_samps,index);
            plot(app.UIAxes,ch1)
            plot(app.UIAxes_2,ch2)
            title(app.UIAxes, sprintf('Channel 1 on %i-%i-%i, index %i', app.date(1), app.date(2), app.date(3), index))
            title(app.UIAxes_2,sprintf('Channel 2 on %i-%i-%i, index %i', app.date(1), app.date(2), app.date(3), index))
        end

        % Button pushed function: ESWButton
        function ESWButtonPushed(app, event)
            index = app.indexes(app.internalindex);
            app.ESW(end+1) = index;
            app.ESW = unique(app.ESW);
            if ismember(index, app.DUST)
                app.DUST = app.DUST(app.DUST~=index);
            end
            if ismember(index, app.EMPTY)
                app.EMPTY = app.EMPTY(app.EMPTY~=index);
            end
            fprintf('ESW: \n')
            disp(app.ESW)
            fprintf('\nDUST: \n')
            disp(app.DUST)
            fprintf('\nEMPTY: \n')
            disp(app.EMPTY)
        end

        % Button pushed function: DUSTButton
        function DUSTButtonPushed(app, event)
            index = app.indexes(app.internalindex);
            app.DUST(end+1) = index;
            app.DUST = unique(app.DUST);
            if ismember(index, app.ESW)
                app.ESW = app.ESW(app.ESW~=index);
            end
            if ismember(index, app.EMPTY)
                app.EMPTY = app.EMPTY(app.EMPTY~=index);
            end
            fprintf('ESW: \n')
            disp(app.ESW)
            fprintf('\nDUST: \n')
            disp(app.DUST)
            fprintf('\nEMPTY: \n')
            disp(app.EMPTY)
        end

        % Button pushed function: EMPTYButton
        function EMPTYButtonPushed(app, event)
            index = app.indexes(app.internalindex);
            app.EMPTY(end+1) = index;
            app.EMPTY = unique(app.EMPTY);
            if ismember(index, app.ESW)
                app.ESW = app.ESW(app.ESW~=index);
            end
            if ismember(index, app.DUST)
                app.DUST = app.DUST(app.DUST~=index);
            end
            fprintf('ESW: \n')
            disp(app.ESW)
            fprintf('\nDUST: \n')
            disp(app.DUST)
            fprintf('\nEMPTY: \n')
            disp(app.EMPTY)
        end

        % Value changed function: DateDatePicker
        function DateDatePickerValueChanged(app, event)
            value = app.DateDatePicker.Value;
            disp(value)
            app.date = [year(value), month(value), day(value)];
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            app.cdftype = lower(selectedButton.Text);
        end

        % Value changed function: makepredictionCheckBox
        function makepredictionCheckBoxValueChanged(app, event)
            value = app.makepredictionCheckBox.Value;
            app.predict = value;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            fname = sprintf('classification learner/data/predicted_%s_%s_%4i%02i%02i.mat',app.etype,app.cdftype,app.date(1),app.date(2),app.date(3));
            if isfile(fname)
                load(fname) %#ok<LOAD>
                app.cdf = cdfdata;
                app.indexes = indexes; %#ok<ADPROPLC>
                app.ogft = ogft; %#ok<ADPROPLC>
                app.ESW = [];
                app.DUST = [];
                app.EMPTY = [];

                STARTButtonPushed(app,event)
            else
                if app.predict == 1
                    disp('predicting')
                    detect_class(app.date(1),app.date(2),app.date(3),app.cdftype,app.etype,'classification learner/models/all data RUSBoosted Trees.mat')
                    LoadButtonPushed(app, event)
                else
                    disp('data missing, make prediction')
                end
            end
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_2.SelectedObject;
            app.etype = lower(selectedButton.Text);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            ft = table();
            dft = table();
            for i = app.DUST
                dft = [dft; app.ogft(i,:)];
            end
            dft.class = repmat("DUST", [size(dft,1), 1] );
            if ~isempty(dft)
                ft = [ft; dft];
            end

            eft = table();
            for i = app.EMPTY
                eft = [eft; app.ogft(i,:)]; %#ok<*AGROW>
            end
            eft.class = repmat("DUST", [size(eft,1), 1] );
            if ~isempty(eft)
                ft = [ft; eft];
            end

            eswft = table();
            for i = app.ESW
                eswft = [eswft; app.ogft(i,:)];
            end
            eswft.class = repmat("DUST", [size(eswft,1), 1] );
            if ~isempty(eswft)
                ft = [ft; eswft];
            end
            esw_indexes = app.ESW;
            dust_indexes = app.DUST;
            empty_indexes = app.EMPTY;
            save(sprintf('classification learner/data/Helper/ft_HELPER_%s_%4i_%02i_%02i.mat',app.cdftype,app.date(1), app.date(2), app.date(3)), 'ft', 'esw_indexes', 'empty_indexes', 'dust_indexes') 
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {646, 646};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {997, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.LeftPanel);
            title(app.UIAxes, 'Channel 1')
            xlabel(app.UIAxes, 'Samples')
            ylabel(app.UIAxes, 'Waveform voltage (V)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.LeftPanel);
            title(app.UIAxes_2, 'Channel 2')
            xlabel(app.UIAxes_2, 'Samples')
            ylabel(app.UIAxes_2, 'Waveform voltage (V)')
            zlabel(app.UIAxes_2, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            app.ESWButton = uibutton(app.RightPanel, 'push');
            app.ESWButton.ButtonPushedFcn = createCallbackFcn(app, @ESWButtonPushed, true);
            app.ESWButton.Text = 'ESW';
            app.DUSTButton = uibutton(app.RightPanel, 'push');
            app.DUSTButton.ButtonPushedFcn = createCallbackFcn(app, @DUSTButtonPushed, true);
            app.DUSTButton.Text = 'DUST';
            app.EMPTYButton = uibutton(app.RightPanel, 'push');
            app.EMPTYButton.ButtonPushedFcn = createCallbackFcn(app, @EMPTYButtonPushed, true);
            app.EMPTYButton.Text = 'EMPTY';
            app.NEXTButton = uibutton(app.RightPanel, 'push');
            app.NEXTButton.ButtonPushedFcn = createCallbackFcn(app, @NEXTButtonPushed, true);
            app.NEXTButton.Text = 'NEXT';
            app.BACKButton = uibutton(app.RightPanel, 'push');
            app.BACKButton.ButtonPushedFcn = createCallbackFcn(app, @BACKButtonPushed, true);
            app.BACKButton.Text = 'BACK';
            app.STARTButton = uibutton(app.RightPanel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.ENDButton = uibutton(app.RightPanel, 'push');
            app.ENDButton.ButtonPushedFcn = createCallbackFcn(app, @ENDButtonPushed, true);
            app.ENDButton.Text = 'END';
            app.DateDatePicker = uidatepicker(app.RightPanel);
            app.DateDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.DateDatePicker.ValueChangedFcn = createCallbackFcn(app, @DateDatePickerValueChanged, true);
            app.makepredictionCheckBox = uicheckbox(app.RightPanel);
            app.makepredictionCheckBox.ValueChangedFcn = createCallbackFcn(app, @makepredictionCheckBoxValueChanged, true);
            app.makepredictionCheckBox.Text = 'make prediction';
            app.ButtonGroup = uibuttongroup(app.RightPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.RSWFButton = uiradiobutton(app.ButtonGroup);
            app.RSWFButton.Text = 'RSWF';
            app.RSWFButton.Value = true;
            app.TSWFButton = uiradiobutton(app.ButtonGroup);
            app.TSWFButton.Text = 'TSWF';
            app.LoadButton = uibutton(app.RightPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ButtonGroup_2 = uibuttongroup(app.RightPanel);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.ESWButton_2 = uiradiobutton(app.ButtonGroup_2);
            app.ESWButton_2.Text = 'ESW';
            app.ESWButton_2.Value = true;
            app.DUSTButton_2 = uiradiobutton(app.ButtonGroup_2);
            app.DUSTButton_2.Text = 'DUST';
            app.SaveButton = uibutton(app.RightPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ClassificationHelperApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end